classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Menu                         matlab.ui.container.Menu
        LoadDataMenu                 matlab.ui.container.Menu
        SavefigButton_4              matlab.ui.control.Button
        SavefigButton_3              matlab.ui.control.Button
        SavefigButton_2              matlab.ui.control.Button
        SavepngButton_4              matlab.ui.control.Button
        SavepngButton_3              matlab.ui.control.Button
        SavepngButton_2              matlab.ui.control.Button
        SavefigButton                matlab.ui.control.Button
        SavepngButton                matlab.ui.control.Button
        SamplesizeEditField          matlab.ui.control.NumericEditField
        SamplesizeEditFieldLabel     matlab.ui.control.Label
        chisquaretestEditField       matlab.ui.control.EditField
        chisquaretestEditFieldLabel  matlab.ui.control.Label
        plotButton                   matlab.ui.control.Button
        ToEditField                  matlab.ui.control.NumericEditField
        ToEditFieldLabel             matlab.ui.control.Label
        FromEditField                matlab.ui.control.NumericEditField
        FromEditFieldLabel           matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filename
        loaded_all_data % načítané všetky dáta
        actual_data     % načítaný vybraný kúsok z dát
        sample_size
        begin_flow
        end_flow
        alpha
        beta
    end
    
    methods (Access = private)

        % Function to handle key presses in the figure
        function figureKeyPress(app, event)
            key = event.Key;
            if strcmp(key, 'return')
                plotButtonPushed(app, event);
            end
        end

        function load_data(app)
            f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [app.filename, path] = uigetfile('*.mat', 'Select MAT File', 'MultiSelect', 'off');
            delete(f);

            % Check if the user canceled file selection
            if isequal(app.filename, 0)
                return;
            end

            % Load data from the file
            loadedData = load(fullfile(path, app.filename));

            plot(app.UIAxes, loadedData.a);
            title(app.UIAxes, "\color{blue}overall IP flow - " + app.filename);

            app.loaded_all_data = loadedData.a;

            % precistit ostatné screeny
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
            
            app.FromEditField.Value = 0;
            app.ToEditField.Value = 0;
            app.SamplesizeEditField.Value = 0;
            app.chisquaretestEditField.Value = '';
        end
   
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function [data, sampled_data] = on_off_function(app)
            % zistenie Ppeak
            maxValue = ceil(max(app.actual_data));
            count_max_value = sum(app.actual_data == max(app.actual_data));
            %count_max_value = sum(data == maxValue);
            Ppeak = count_max_value/length(app.actual_data);
            
            % zistenie lambda_avg
            lambda_avg = mean(app.actual_data);
            
            % vzorce clanok
            app.alpha = 1 - (Ppeak * maxValue/lambda_avg)^(1/(maxValue-1));
            app.beta = (lambda_avg * app.alpha) / (maxValue - lambda_avg);
            
            % ON OFF 
            app.sample_size = maxValue;
            pocet_generovanych = (app.end_flow - app.begin_flow) * app.sample_size;
            stav = 1;
            
            for i=1:pocet_generovanych
                if stav == 1
                    generated_data(i) = 1;
            
                    pravd = 1.*rand();
                    if pravd >= (1 - app.alpha)
                        stav = 0;
                    end
                else
                    generated_data(i) = 0;
            
                    pravd = 1.*rand();
                    if pravd >= (1-app.beta)
                        stav = 1;
                    end
                end
            end
            
            % samplovanie dat
            sampled_data = zeros(1,ceil(pocet_generovanych/app.sample_size));
            
            pom_sum = sum(generated_data(1:app.sample_size));
            sampled_data(1) = pom_sum;
            
            for i=1:(pocet_generovanych/app.sample_size)-1
                pom_sum = sum(generated_data((i*app.sample_size)+1:(i*app.sample_size)+app.sample_size));
                sampled_data(i+1) = pom_sum;
            end


            %plot data + generated
            cla(app.UIAxes2) 
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, app.actual_data, 'b');
            plot(app.UIAxes2, length(app.actual_data) + (1:length(sampled_data)), sampled_data, 'r');
            xlim(app.UIAxes2, [1, length(sampled_data)*2+1]);
            legend(app.UIAxes2,'flow','generated data')

            % plot histogram data a histogram generated
            clear hist_data;
            clear hist_generated;
            hist_data = histogram(app.UIAxes3, app.actual_data, 'Normalization', 'probability');
            hist_generated = histogram(app.UIAxes4, sampled_data, 'Normalization', 'probability','NumBins',hist_data.NumBins);

            % výška Y osi histogramov - ak max(flow) > max(mmrp)
            if max(hist_data.Values) >= max(hist_generated.Values)
                hist_generated_Y_os = ceil(max(hist_data.Values) * 100) / 100;
                ylim(app.UIAxes4,[0, hist_generated_Y_os]);
                ylim(app.UIAxes3,[0, hist_generated_Y_os]);
            else
                % ak max(mmrp) > max(flow)
                hist_data_Y_os = ceil(max(hist_generated.Values) * 100) / 100;
                ylim(app.UIAxes3,[0, hist_data_Y_os]);
                ylim(app.UIAxes4,[0, hist_data_Y_os]);
            end

            % chi square test
            chisquaretest(app,hist_data.Values, hist_generated.Values);


            %chisquaretest2(app,hist_data.Values, hist_generated.Values);


            % kapacity
            vypocitaj_kapacity(app,length(hist_generated.Values));
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function vypocitaj_kapacity(app,pocet_tried)
            
                %for i=1:2000
                    %th = i/1000;
                    %mmrp_kapacita(i) = pocet_tried/th * log( ( 1 - app.beta + (1-app.alpha)*exp(th) + sqrt( ( (1-app.alpha)*exp(th) - (1-app.beta) )^2 + 4*app.alpha*app.beta*exp(th)) ) / 2 );
                %end

                %th = linspace(0.01,2,1000);
                %mmrp_kapacita = pocet_tried./th;
                %disp(mmrp_kapacita);
                
                % MMRP kapacita
                pi1 = app.beta / (app.alpha+app.beta);
                capacity_mmrp(1:length(app.actual_data)) = app.sample_size * pi1;

                compute_window = app.SamplesizeEditField.Value;
                capacity_flow = zeros(1,length(app.actual_data)-(compute_window+1));

                % Flow kapacita
                for i=compute_window+1:length(app.actual_data)%-1
                    data_cw  = app.actual_data(i-compute_window:i);
                    capacity_flow(i) = vypocitaj_flow_c(app,data_cw);
                end

                plot(app.UIAxes2, capacity_flow, 'black');
                plot(app.UIAxes2, length(capacity_flow) + (1:length(capacity_mmrp)), capacity_mmrp, 'black');
                hold(app.UIAxes2, 'off');
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function c = vypocitaj_flow_c(app,data_cw)
                d = 100;
                Plost = 0.1;
                Y = (log(Plost))/(-d);

                max_number = ceil(max(data_cw));
                % zistenie pravdepodobností pre každú hodnotu
                for k=0:max_number
                    n = 0;
                    %n = numel(find(app.loaded_data==k));
                    n = numel(find(data_cw >= k-0.5 & data_cw <= k+0.5));
                    pdf(k+1) = n/length(data_cw);
                end
                dlzkaPdf = length(pdf);
            
                % vypocet thety
                theta = 0.001;
                
                while true
                    for k=1:dlzkaPdf
                        pom(k) = (exp(theta*(k-1)))*pdf(k); % má to byť pom(k) ??
                    end
                    lambda_theta = log(sum(pom)); % tunaka to môže robiť problémy idk
                    if lambda_theta >= Y
                        break
                    end
                    theta = theta + 0.001;
                end
                c = lambda_theta/theta;
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % mam lepsi kalkulus pre chisquaretest
        %{
        function chi2_stat = chisquaretest(expected, observed)
            pseudo_count = 1;
            chi2_stat = sum((observed - expected).^2 ./ (expected + pseudo_count));
        end
        %}

        function chisquaretest(app, flow_data, mmrp_data) % mam lepsi kalkulus
            vysl = 0;
            for i=1:length(flow_data)

                if flow_data(i) == 0
                    flow_data(i) = eps; %0.00001;
                end

                vysl = vysl + ( ( (mmrp_data(i) - flow_data(i))^2 ) / flow_data(i) );
            end
            app.chisquaretestEditField.Value = num2str(vysl);
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %{
        function chisquaretest2(app, data1, data2)
            % Calculate the total observations for each dataset
            total1 = sum(data1);
            total2 = sum(data2);
            
            % Calculate the proportions for each category
            proportions1 = histcounts(data1, 1:max(data1)+1) / total1;
            proportions2 = histcounts(data2, 1:max(data2)+1) / total2;
            
            % Calculate the expected frequencies for each dataset
            expected1 = total1 * proportions1;
            expected2 = total2 * proportions2;
            
            % Add a small constant to avoid division by zero
            epsilon = 0.01;
            expected1 = expected1 + epsilon;
            expected2 = expected2 + epsilon;
            
            % Calculate the chi-square statistic
            chi2statistic = sum((histcounts(data1, 1:max(data1)+1) - expected1).^2 ./ expected1) + ...
                            sum((histcounts(data2, 1:max(data2)+1) - expected2).^2 ./ expected2);
            
            % Number of categories in each dataset
            num_categories1 = numel(unique(data1));
            num_categories2 = numel(unique(data2));
            
            % Calculate the degrees of freedom
            df = (num_categories1 - 1) + (num_categories2 - 1);
            
            % Set Significance Level
            chi_alpha = 0.05;
            
            % Look up Critical Value from Chi-Square Distribution Table
            critical_value = chi2inv(1 - chi_alpha, df);
            
            % Compare with Calculated Chi-Square Statistic
            if chi2statistic > critical_value
                formatted_string = sprintf('NIE SU PODOBNE.\n chi2statistic: %.6f \n critical_value: %.6f', chi2statistic, critical_value);
            else
                formatted_string = sprintf('SU PODOBNE.\n chi2statistic: %.6f \n critical_value: %.6f', chi2statistic, critical_value);
            end
            app.chisquaretest2EditField.Value = formatted_string;
        end
        %}
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.UIFigure.KeyPressFcn = @(src, event) figureKeyPress(app, event);

            app.FromEditField.ValueDisplayFormat = '%.0f';
            app.ToEditField.ValueDisplayFormat = '%.0f';
            app.SamplesizeEditField.ValueDisplayFormat = '%.0f';

            title(app.UIAxes, "\color{blue}overall IP flow");
            title(app.UIAxes2,"\color{blue}flow \color{black}/ \color{red}generated data");
            title(app.UIAxes3,"\color{blue}histogram flow");
            title(app.UIAxes4,"\color{red}histogram generated data");
            
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            load_data(app);
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            % check či sú načítané dáta
            if isempty(app.loaded_all_data)
                errordlg('Nie sú načítané dáta!', 'Error', 'modal');
                return
            end
            
            % či nie su FROM TO zaporne
            if app.FromEditField.Value < 0 || app.ToEditField.Value <= 0
                errordlg('Musíš zadať číslo väčšie ako 0! (In "TO")', 'Error', 'modal');
                return
            end
            % či nie je FROM a to väčšie ako veľkosť dát
            if app.FromEditField.Value > length(app.loaded_all_data) || app.ToEditField.Value > length(app.loaded_all_data)
                errordlg('Musíš zadať číslo menšie ako veľkosť dát!', 'Error', 'modal');
                return
            end
            % TO musí byť menšie ako FROM+10
            if (app.FromEditField.Value + 10) > app.ToEditField.Value
                errordlg('"TO" musí byť väčšie číslo ako "FROM"! (minimálne o 10)', 'Error', 'modal');
                return
            end
            % TO musí byť väčšie ako 9
            if app.ToEditField.Value <= 9
                errordlg('"TO" musí byť väčšie ako 9!', 'Error', 'modal');
                return
            end

            app.begin_flow = app.FromEditField.Value + 1;
            app.end_flow = app.ToEditField.Value;

            app.actual_data = app.loaded_all_data(app.begin_flow:app.end_flow);


            % SampleSize musí byť aspon 10
            if app.SamplesizeEditField.Value < 10
                errordlg('Sample size musí byť minimálne 10!', 'Error', 'modal');
                return
            end

            % SampleSize musí byť menšie ako aktuálne dáta
            if app.SamplesizeEditField.Value >= length(app.actual_data)
                errordlg('Sample size nemôže byť väčšie číslo ako veľkosť dát!', 'Error', 'modal');
                return
            end

            try
                on_off_function(app);
            catch exception
                errordlg(exception.message, 'Error', 'modal');
                return
            end
            %{
            if ~isempty(app.loaded_all_data)
                % či nie su FROM TO zaporne
                if app.FromEditField.Value >= 0 && app.ToEditField.Value > 0
                    % či nie je FROM a to väčšie ako veľkosť dát
                    if app.FromEditField.Value <= length(app.loaded_all_data) && app.ToEditField.Value <= length(app.loaded_all_data)
                        % TO musí byť menšie ako FROM
                        if (app.FromEditField.Value + 10) <= app.ToEditField.Value
                            % TO musí byť väčšie ako 9
                            if app.ToEditField.Value > 9
                                try
                                    on_off_function(app);
                                catch exception
                                    errordlg(exception.message, 'Error', 'modal');
                                end
                            else
                                errordlg('"TO" musí byť väčšie ako 9!', 'Error', 'modal');
                            end
                        else
                            errordlg('"TO" musí byť väčšie číslo ako "FROM"! (minimálne o 10)', 'Error', 'modal');
                        end
                    else
                        errordlg('Musíš zadať číslo menšie ako veľkosť dát!', 'Error', 'modal');
                    end
                else
                    errordlg('Musíš zadať číslo väčšie ako 0!', 'Error', 'modal');
                end
            
        end
        %}
        end

        % Value changed function: FromEditField
        function FromEditFieldValueChanged(app, event)
            %{
            value = app.FromEditField.Value;
            key = event.Key;
            if strcmp(key, 'return')
                plotButtonPushed(app, event);
            end
            %}
        end

        % Button pushed function: SavepngButton
        function SavepngButtonPushed(app, event)
            exportgraphics(app.UIAxes,"Data.png");
        end

        % Button pushed function: SavefigButton
        function SavefigButtonPushed(app, event)

            % Create a new figure
            h = figure; 

            % Copy the data from the UIAxes to local variables
            x = app.UIAxes.XAxis.Parent.Children.XData;
            y = app.UIAxes.XAxis.Parent.Children.YData;
        
            % Plot the data in the new figure
            plot(x, y);
        
            % Copy the properties from the UIAxes to the new figure
            h.CurrentAxes.YLabel.String = app.UIAxes.YLabel.String;
            h.CurrentAxes.YLabel.FontSize = app.UIAxes.YLabel.FontSize;
            h.CurrentAxes.XLabel.String = app.UIAxes.XLabel.String;
            h.CurrentAxes.XLabel.FontSize = app.UIAxes.XLabel.FontSize;
            h.CurrentAxes.Title.String = app.UIAxes.Title.String;
            h.CurrentAxes.Title.FontSize = app.UIAxes.Title.FontSize;
            h.CurrentAxes.XLim = [0 max(x)];
            h.CurrentAxes.YLim = [0 max(y)+1];
        
            % Save the figure
            savefig(h, 'Data.fig');
        
            % Delete the figure
            delete(h);
            
        end

        % Button pushed function: SavepngButton_2
        function SavepngButton_2Pushed(app, event)
            exportgraphics(app.UIAxes2,"MMRP.png");
        end

        % Button pushed function: SavepngButton_4
        function SavepngButton_4Pushed(app, event)
            exportgraphics(app.UIAxes3,"FlowHistogram.png");
        end

        % Button pushed function: SavepngButton_3
        function SavepngButton_3Pushed(app, event)
            exportgraphics(app.UIAxes4,"MMRPHistogram.png");
        end

        % Button pushed function: SavefigButton_2
        function SavefigButton_2Pushed(app, event)
% Save the UIAxes to a .fig file
f = uifigure;
ax = copyobj(app.UIAxes2, f);

% Set the units to normalized
ax.Units = 'normalized';

% Adjust position and size as needed
% For example, to make the axes fill the entire figure window
ax.Position = [0 0 1 1];  

savefig(f, 'MMRP_flow.fig');
close(f);


%ax.Position = [70 70 460 440];fullscreen solid  

        end

        % Button pushed function: SavefigButton_3
        function SavefigButton_3Pushed(app, event)
        % Create a new figure
        h = figure; 
        % Copy the data from the UIAxes to local variables
        values = app.UIAxes3.Children.Values;
        binEdges = app.UIAxes3.Children.BinEdges;
    
        % Plot the data
        histogram('BinEdges',binEdges,'BinCounts',values)
    
        % Copy the properties from the UIAxes to the new figure
        h.CurrentAxes.YLabel.String = app.UIAxes3.YLabel.String;
        h.CurrentAxes.YLabel.FontSize = app.UIAxes3.YLabel.FontSize;
        h.CurrentAxes.XLabel.String = app.UIAxes3.XLabel.String;
        h.CurrentAxes.XLabel.FontSize = app.UIAxes3.XLabel.FontSize;
        h.CurrentAxes.Title.String = app.UIAxes3.Title.String;
        h.CurrentAxes.Title.FontSize = app.UIAxes3.Title.FontSize;
    
        % Save the figure
        savefig(h,'Flow_hist.fig')
    
        % Delete the figure
        delete(h)
        end

        % Button pushed function: SavefigButton_4
        function SavefigButton_4Pushed(app, event)
            % Create a new figure
            h = figure; 
            % Copy the data from the UIAxes to local variables
            values = app.UIAxes4.Children.Values;
            binEdges = app.UIAxes4.Children.BinEdges;
        
            % Plot the data
            histogram('BinEdges',binEdges,'BinCounts',values)
        
            % Copy the properties from the UIAxes to the new figure
            h.CurrentAxes.YLabel.String = app.UIAxes4.YLabel.String;
            h.CurrentAxes.YLabel.FontSize = app.UIAxes4.YLabel.FontSize;
            h.CurrentAxes.XLabel.String = app.UIAxes4.XLabel.String;
            h.CurrentAxes.XLabel.FontSize = app.UIAxes4.XLabel.FontSize;
            h.CurrentAxes.Title.String = app.UIAxes4.Title.String;
            h.CurrentAxes.Title.FontSize = app.UIAxes4.Title.FontSize;
        
            % Save the figure
            savefig(h,'MMRP_hist.fig')
        
            % Delete the figure
            delete(h)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadDataMenu = uimenu(app.Menu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Text = 'Load Data';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Seconds')
            ylabel(app.UIAxes2, 'Values')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'Classes')
            ylabel(app.UIAxes3, 'Probabilities')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'Classes')
            ylabel(app.UIAxes4, 'Probabilities')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Seconds')
            ylabel(app.UIAxes, 'Values')
            zlabel(app.UIAxes, 'Z')
            app.FromEditFieldLabel = uilabel(app.UIFigure);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.UIFigure, 'numeric');
            app.FromEditField.ValueChangedFcn = createCallbackFcn(app, @FromEditFieldValueChanged, true);
            app.ToEditFieldLabel = uilabel(app.UIFigure);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.UIFigure, 'numeric');
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.chisquaretestEditFieldLabel = uilabel(app.UIFigure);
            app.chisquaretestEditFieldLabel.Text = 'chi square test';
            app.chisquaretestEditField = uieditfield(app.UIFigure, 'text');
            app.chisquaretestEditField.Editable = 'off';
            app.SamplesizeEditFieldLabel = uilabel(app.UIFigure);
            app.SamplesizeEditFieldLabel.Text = 'Sample size';
            app.SamplesizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SavepngButton = uibutton(app.UIFigure, 'push');
            app.SavepngButton.ButtonPushedFcn = createCallbackFcn(app, @SavepngButtonPushed, true);
            app.SavepngButton.Text = 'Save png';
            app.SavefigButton = uibutton(app.UIFigure, 'push');
            app.SavefigButton.ButtonPushedFcn = createCallbackFcn(app, @SavefigButtonPushed, true);
            app.SavefigButton.Text = 'Save fig';
            app.SavepngButton_2 = uibutton(app.UIFigure, 'push');
            app.SavepngButton_2.ButtonPushedFcn = createCallbackFcn(app, @SavepngButton_2Pushed, true);
            app.SavepngButton_2.Text = 'Save png';
            app.SavepngButton_3 = uibutton(app.UIFigure, 'push');
            app.SavepngButton_3.ButtonPushedFcn = createCallbackFcn(app, @SavepngButton_3Pushed, true);
            app.SavepngButton_3.Text = 'Save png';
            app.SavepngButton_4 = uibutton(app.UIFigure, 'push');
            app.SavepngButton_4.ButtonPushedFcn = createCallbackFcn(app, @SavepngButton_4Pushed, true);
            app.SavepngButton_4.Text = 'Save png';
            app.SavefigButton_2 = uibutton(app.UIFigure, 'push');
            app.SavefigButton_2.ButtonPushedFcn = createCallbackFcn(app, @SavefigButton_2Pushed, true);
            app.SavefigButton_2.Text = 'Save fig';
            app.SavefigButton_3 = uibutton(app.UIFigure, 'push');
            app.SavefigButton_3.ButtonPushedFcn = createCallbackFcn(app, @SavefigButton_3Pushed, true);
            app.SavefigButton_3.Text = 'Save fig';
            app.SavefigButton_4 = uibutton(app.UIFigure, 'push');
            app.SavefigButton_4.ButtonPushedFcn = createCallbackFcn(app, @SavefigButton_4Pushed, true);
            app.SavefigButton_4.Text = 'Save fig';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end