classdef appntbk < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        LoadDataMenu                  matlab.ui.container.Menu
        chisquaretest2EditField       matlab.ui.control.EditField
        chisquaretest2EditFieldLabel  matlab.ui.control.Label
        chisquaretestEditField        matlab.ui.control.EditField
        chisquaretestEditFieldLabel   matlab.ui.control.Label
        FlowcapacityEditField         matlab.ui.control.NumericEditField
        FlowcapacityEditFieldLabel    matlab.ui.control.Label
        MMRPcapacityEditField         matlab.ui.control.NumericEditField
        MMRPcapacityEditFieldLabel    matlab.ui.control.Label
        plotButton                    matlab.ui.control.Button
        ToEditField                   matlab.ui.control.NumericEditField
        ToEditFieldLabel              matlab.ui.control.Label
        FromEditField                 matlab.ui.control.NumericEditField
        FromEditFieldLabel            matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        loaded_all_data % načítané všetky dáta
        actual_data     % načítaný vybraný kúsok z dát
        sample_size
        alpha
        beta
        filename
    end
    
    methods (Access = private)
        
        function load_data(app)
            [app.filename, path] = uigetfile('*.mat', 'Select MAT File');

            % Check if the user canceled file selection
            if isequal(app.filename, 0)
                return;
            end

            % Load data from the file
            loadedData = load(fullfile(path, app.filename));

            plot(app.UIAxes, loadedData.a);
            title(app.UIAxes, "\color{blue}overall IP flow - " + app.filename);

            app.loaded_all_data = loadedData.a;

            % precistit ostatné screeny
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
            
        end
   
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function [data, sampled_data] = on_off_function(app)
            begin_flow = app.FromEditField.Value + 1;
            end_flow = app.ToEditField.Value;


            data = app.loaded_all_data(begin_flow:end_flow);
            app.actual_data = app.loaded_all_data(begin_flow:end_flow);


            % zistenie Ppeak
            maxValue = ceil(max(data));
            count_max_value = sum(data == max(data));
            %count_max_value = sum(data == maxValue);
            Ppeak = count_max_value/length(data);
            
            % zistenie lambda_avg
            lambda_avg = mean(data);
            
            % vzorce clanok
            app.alpha = 1 - (Ppeak * maxValue/lambda_avg)^(1/(maxValue-1));
            app.beta = (lambda_avg * app.alpha) / (maxValue - lambda_avg);
            
            % ON OFF 
            
            app.sample_size = maxValue;
            pocet_generovanych = (end_flow - begin_flow) * app.sample_size;
            stav = 1;
            
            
            for i=1:pocet_generovanych
                if stav == 1
                    generated_data(i) = 1;
            
                    pravd = 1.*rand();
                    if pravd >= (1 - app.alpha)
                        stav = 0;
                    end
                else
                    generated_data(i) = 0;
            
                    pravd = 1.*rand();
                    if pravd >= (1-app.beta)
                        stav = 1;
                    end
                end
            end
            
            % samplovanie dat
            sampled_data = zeros(1,ceil(pocet_generovanych/app.sample_size));
            
            pom_sum = sum(generated_data(1:app.sample_size));
            sampled_data(1) = pom_sum;
            
            for i=1:(pocet_generovanych/app.sample_size)-1
                pom_sum = sum(generated_data((i*app.sample_size)+1:(i*app.sample_size)+app.sample_size));
                sampled_data(i+1) = pom_sum;
            end

            % kapacity
            vypocitaj_kapacity(app)


            %plot data + generated
            cla(app.UIAxes2) 
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, data, 'b');
            plot(app.UIAxes2, length(data) + (1:length(sampled_data)), sampled_data, 'r');
            xlim(app.UIAxes2, [1, length(sampled_data)*2+1]);
            legend(app.UIAxes2,'flow','generated data')
            hold(app.UIAxes2, 'off');

            % plot histogram data a histogram generated
            hist_data = histogram(app.UIAxes3, data);
            hist_generated = histogram(app.UIAxes4, sampled_data,'NumBins',hist_data.NumBins);

            % chi square test
            chisquaretest(app,hist_data.Values, hist_generated.Values);
            chisquaretest2(app,hist_data.Values, hist_generated.Values);
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function vypocitaj_kapacity(app)
                % MMRP kapacita
                pi1 = app.beta / (app.alpha+app.beta);
                app.MMRPcapacityEditField.Value = app.sample_size * pi1;

                % Flow kapacita
                d = 100;
                Plost = 0.1;
                Y = (log(Plost))/(-d);

                max_number = ceil(max(app.actual_data));
                for k=0:max_number
                    n = 0;
                    %n = numel(find(app.loaded_data==k));
                    n = numel(find(app.actual_data >= k-0.5 & app.actual_data <= k+0.5));
                    pdf(k+1) = n/length(app.actual_data);
                end
                dlzkaPdf = length(pdf);
            
                % vypocet thety
                theta = 0.001;
                
                while true
                    for k=1:dlzkaPdf
                        pom(k) = (exp(theta*(k-1)))*pdf(k);
                    end
                    lambda_theta = log(sum(pom));
                    if lambda_theta >= Y
                        break
                    end
                    theta = theta + 0.001;
                end
            
                app.FlowcapacityEditField.Value = lambda_theta/theta;
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function chisquaretest(app, data1, data2)

            chi_alpha = 0.05;
            
            % Combine the data into a contingency table
            chi_data = [data1; data2];
            
            % Calculate expected frequencies
            expectedFrequencies = mean(chi_data, 2);
            
            % Compute chi-square statistic
            chi2Statistic = sum((chi_data - expectedFrequencies).^2 ./ expectedFrequencies);
            
            % Calculate degrees of freedom
            degreesOfFreedom = (size(chi_data, 1) - 1) * (size(chi_data, 2) - 1);
            
            % Calculate p-value
            pValue = chi2cdf(chi2Statistic, degreesOfFreedom);
            
            % vytvorenie hranice úspechu
            if mod(length(data1),2) ~= 0
                hranica = ceil(length(data1) / 2); % ak vyjde 3,5 tak bude 4
            else
                hranica = length(data1) / 2;
            end
            
            % spočítanie pocet mensich než alpha
            pocet_mensich = 0;
            for number = pValue
                if number < chi_alpha
                    %fprintf('p-value = %f GUCCI\n', number);
                    pocet_mensich = pocet_mensich + 1;
                else
                    %fprintf('p-value = %f ZLE\n', number);
                end
            end
            
            % print výsledku
            if hranica > pocet_mensich
                %formatted_string = sprintf("NIE SU PODOBNE, počet_menších = %d z %d, hranica=%d", pocet_mensich,length(data1),hranica);
                formatted_string = char("NIE SU PODOBNE, počet_menších = " + num2str(pocet_mensich) + " z " + num2str(length(data1)) + ", hranica=" + num2str(hranica));
            else
                %formatted_string = sprintf("SU PODOBNE, počet_menších = %d z %d, hranica=%d", pocet_mensich,length(data1),hranica);
                formatted_string = char("SU PODOBNE, počet_menších = " + num2str(pocet_mensich) + " z " + num2str(length(data1)) + ", hranica=" + num2str(hranica));
            end
            app.chisquaretestEditField.Value = formatted_string;
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function chisquaretest2(app, data1, data2)
            % Calculate the total observations for each dataset
            total1 = sum(data1);
            total2 = sum(data2);
            
            % Calculate the proportions for each category
            proportions1 = histcounts(data1, 1:max(data1)+1) / total1;
            proportions2 = histcounts(data2, 1:max(data2)+1) / total2;
            
            % Calculate the expected frequencies for each dataset
            expected1 = total1 * proportions1;
            expected2 = total2 * proportions2;
            
            % Add a small constant to avoid division by zero
            epsilon = 0.01;
            expected1 = expected1 + epsilon;
            expected2 = expected2 + epsilon;
            
            % Calculate the chi-square statistic
            chi2statistic = sum((histcounts(data1, 1:max(data1)+1) - expected1).^2 ./ expected1) + ...
                            sum((histcounts(data2, 1:max(data2)+1) - expected2).^2 ./ expected2);
            
            % Number of categories in each dataset
            num_categories1 = numel(unique(data1));
            num_categories2 = numel(unique(data2));
            
            % Calculate the degrees of freedom
            df = (num_categories1 - 1) + (num_categories2 - 1);
            
            % Set Significance Level
            chi_alpha = 0.05;
            
            % Look up Critical Value from Chi-Square Distribution Table
            critical_value = chi2inv(1 - chi_alpha, df);
            
            % Compare with Calculated Chi-Square Statistic
            if chi2statistic > critical_value
                formatted_string = sprintf('NIE SU PODOBNE.\n chi2statistic: %.6f \n critical_value: %.6f', chi2statistic, critical_value);
            else
                formatted_string = sprintf('SU PODOBNE.\n chi2statistic: %.6f \n critical_value: %.6f', chi2statistic, critical_value);
            end
            app.chisquaretest2EditField.Value = formatted_string;
        end
    end
    
    methods (Access = public)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FromEditField.ValueDisplayFormat = '%.0f';
            app.ToEditField.ValueDisplayFormat = '%.0f';

            title(app.UIAxes, "\color{blue}overall IP flow");
            title(app.UIAxes2,"\color{blue}flow \color{black}/ \color{red}generated data");
            title(app.UIAxes3,"\color{blue}histogram flow");
            title(app.UIAxes4,"\color{red}histogram generated data");
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            load_data(app);
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            if ~isempty(app.loaded_all_data)
                % či nie su FROM TO zaporne
                if app.FromEditField.Value >= 0 && app.ToEditField.Value > 0
                    % či nie je FROM a to väčšie ako veľkosť dát
                    if app.FromEditField.Value <= length(app.loaded_all_data) && app.ToEditField.Value <= length(app.loaded_all_data)
                        % TO musí byť menšie ako FROM
                        if (app.FromEditField.Value + 10) <= app.ToEditField.Value
                            % TO musí byť väčšie ako 9
                            if app.ToEditField.Value > 9
                                try
                                    on_off_function(app);
                                catch exception
                                    errordlg(exception.message, 'Error', 'modal');
                                end
                            else
                                errordlg('"TO" musí byť väčšie ako 9!', 'Error', 'modal');
                            end
                        else
                            errordlg('"TO" musí byť väčšie číslo ako "FROM"! (minimálne o 10)', 'Error', 'modal');
                        end
                    else
                        errordlg('Musíš zadať číslo menšie ako veľkosť dát!', 'Error', 'modal');
                    end
                else
                    errordlg('Musíš zadať číslo väčšie ako 0!', 'Error', 'modal');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadDataMenu = uimenu(app.Menu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Text = 'Load Data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.FromEditFieldLabel = uilabel(app.UIFigure);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToEditFieldLabel = uilabel(app.UIFigure);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.UIFigure, 'numeric');
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.MMRPcapacityEditFieldLabel = uilabel(app.UIFigure);
            app.MMRPcapacityEditFieldLabel.Text = 'MMRP capacity';
            app.MMRPcapacityEditField = uieditfield(app.UIFigure, 'numeric');
            app.MMRPcapacityEditField.Editable = 'off';
            app.FlowcapacityEditFieldLabel = uilabel(app.UIFigure);
            app.FlowcapacityEditFieldLabel.Text = 'Flow capacity';
            app.FlowcapacityEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlowcapacityEditField.Editable = 'off';
            app.chisquaretestEditFieldLabel = uilabel(app.UIFigure);
            app.chisquaretestEditFieldLabel.Text = 'chi square test';
            app.chisquaretestEditField = uieditfield(app.UIFigure, 'text');
            app.chisquaretestEditField.Editable = 'off';
            app.chisquaretest2EditFieldLabel = uilabel(app.UIFigure);
            app.chisquaretest2EditFieldLabel.Text = 'chi square test2';
            app.chisquaretest2EditField = uieditfield(app.UIFigure, 'text');
            app.chisquaretest2EditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appntbk

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end